classdef ReconstructionAlgo_Mac_version_v_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectReconstructionAlgorithmUIFigure  matlab.ui.Figure
        Panel         matlab.ui.container.Panel
        OKButton      matlab.ui.control.StateButton
        CancelButton  matlab.ui.control.StateButton
        ReconstructionalgorithmDropDownLabel  matlab.ui.control.Label
        ReconstructionalgorithmDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        %         CallingApp
        val
        reco
        %         cancel
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulatorCTlab, reco)
            %% STARTUP PARAMETERS
            
            
            %Original scren size
            origX = 1920;
            origY = 1080;
            screen = get(groot,'Screensize');
            currenX = screen(3);
            currentY=screen(4);
            sizeX = currenX/origX;
            sizeY = currentY/origY;
            
            
            if screen == [1 1 1920 1080]
                %CTlab-App position in computer screen
                app.SelectReconstructionAlgorithmUIFigure.Position = [690 500 432 199];
            elseif screen == [1 1 1680 1050]
                %CTlab-App position in computer screen
                app.SelectReconstructionAlgorithmUIFigure.Position = [690*sizeX 500*sizeY 432*sizeX 199*sizeY];
            elseif screen == [1 1 1600 900]
                %CTlab-App position in computer screen
                app.SelectReconstructionAlgorithmUIFigure.Position = [690*sizeX 500*sizeY 432*sizeX 199*sizeY];
            end
            
            
            app.SelectReconstructionAlgorithmUIFigure.Position = [690 500 432 199];
            %            app.CallingApp = MainApp;
            app.val = simulatorCTlab;
            %            app.reco = 'Filtered backprojection (FBP)'; %FBP valikossa käynnistettäessä
            app.ReconstructionalgorithmDropDown.Items = {'Filtered backprojection (FBP)', 'Least squares', 'Tikhonov Regularization'}; %Algoritmi vaihtoehdot
        end

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            %             app.cancel = 'ok'
            ChosenReconstruction(app.val, app.ReconstructionalgorithmDropDown.Value);
            delete(app)
        end

        % Close request function: 
        % SelectReconstructionAlgorithmUIFigure
        function SelectReconstructionAlgorithmUIFigureCloseRequest(app, event)
            CancelReconstructionSelection(app.val)
            delete(app)
        end

        % Value changed function: CancelButton
        function CancelButtonValueChanged(app, event)
            CancelReconstructionSelection(app.val)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectReconstructionAlgorithmUIFigure = uifigure('Visible', 'off');
            app.SelectReconstructionAlgorithmUIFigure.Name = 'Select Reconstruction Algorithm';
            app.SelectReconstructionAlgorithmUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectReconstructionAlgorithmUIFigureCloseRequest, true);
            app.Panel = uipanel(app.SelectReconstructionAlgorithmUIFigure);
            app.OKButton = uibutton(app.Panel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.Panel, 'state');
            app.CancelButton.ValueChangedFcn = createCallbackFcn(app, @CancelButtonValueChanged, true);
            app.CancelButton.Text = 'Cancel';
            app.ReconstructionalgorithmDropDownLabel = uilabel(app.Panel);
            app.ReconstructionalgorithmDropDownLabel.Text = 'Reconstruction algorithm:';
            app.ReconstructionalgorithmDropDown = uidropdown(app.Panel);
            app.ReconstructionalgorithmDropDown.Items = {'Filtered backprojection (FBP)', 'Least squares', 'Tikhonov Regularization'};
            app.ReconstructionalgorithmDropDown.ItemsData = {'Filtered backprojection (FBP)', 'Least squares', 'Tikhonov Regularization', 'Gradient descent', ''};
            app.ReconstructionalgorithmDropDown.Value = 'Filtered backprojection (FBP)';
            % Show the figure after all components are created
            app.SelectReconstructionAlgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReconstructionAlgo_Mac_version_v_1_0(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectReconstructionAlgorithmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectReconstructionAlgorithmUIFigure)
        end
    end
end